classdef Fruit_Health_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AnalysisLabel          matlab.ui.control.Label
        UploadyourImageButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [defects, full, defected_count, full_count] = func(app, image)
            [defects, defected_count]= Identification(image);
            [full, full_count]= Full(image);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadyourImageButton
        function UploadyourImageButtonPushed(app, event)

           [file, path] = uigetfile('*.*');
           input = imread(file);
           
           im = uiimage(app.UIFigure);
           im.Position = [30 220 200 250];
           im.ImageSource = file;
           
           label1 = uilabel(app.UIFigure);
           label1.FontSize = 14;
           label1.Position = [30 420 100 75];
           label1.Text = 'Input Image';
           
           [defects, full, defected_count, full_count]= func(app, input);
      
           imwrite(defects, 'defects.jpg');
           imwrite(full, 'full_fruit.jpg');
           
           im2 = uiimage(app.UIFigure);
           im2.Position = [225 220 200 250];
           im2.ImageSource = 'defects.jpg';
           
           label1 = uilabel(app.UIFigure);
           label1.FontSize = 14;
           label1.Position = [225 420 100 75];
           label1.Text = 'Defected Spots';
           
           im3 = uiimage(app.UIFigure);
           im3.Position = [425 220 200 250];
           im3.ImageSource = 'full_fruit.jpg';
           
           label1 = uilabel(app.UIFigure);
           label1.FontSize = 14;
           label1.Position = [425 420 100 75];
           label1.Text = 'Full Fruit Area';
           
           output = uilabel(app.UIFigure);
           output.FontSize = 20;
           output.Position = [175 134 100 75];
           
           result = defected_count/full_count;
           
           output.Text = string(result);
           
           
           output2 = uilabel(app.UIFigure);
           output2.FontSize = 14;
           output2.Position = [300 134 400 75];
           if ((0.8 > result) && ( result >= 0))
               output2.Text = 'this fruit does not meet our threshold';
           else
               output2.Text = 'this fruit is healthy for consumption';
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadyourImageButton = uibutton(app.UIFigure, 'push');
            app.UploadyourImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadyourImageButtonPushed, true);
            app.UploadyourImageButton.Text = 'Upload your Image';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fruit_Health_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end