classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AnalysisLabel          matlab.ui.control.Label
        UploadyourImageButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = func(app, image)
            results = Test(image);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadyourImageButton
        function UploadyourImageButtonPushed(app, event)
           [file, path] = uigetfile('./Image_Dataset/*.*');
           
           
           im = uiimage(app.UIFigure);
           im.Position = [142 226 364 238];
           im.ImageSource = file;
           
           result = func(app, file);
           
           output = uilabel(app.UIFigure);
           output.FontSize = 20;
           output.Position = [230 134 466 75];
           output.Text = result;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadyourImageButton = uibutton(app.UIFigure, 'push');
            app.UploadyourImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadyourImageButtonPushed, true);
            app.UploadyourImageButton.Text = 'Upload your Image';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end